classdef FlowProcessing2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FlowProcessingUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadingandPreprocessingTab      matlab.ui.container.Tab
        ProcessingPanel                 matlab.ui.container.Panel
        MapsPushButton                  matlab.ui.control.Button
        PulseWaveVelocityPushButton     matlab.ui.control.Button
        UnwrapVelocity                  matlab.ui.control.Button
        DVisualizationPanel             matlab.ui.container.Panel
        ResetRotation                   matlab.ui.control.Button
        RotateUp                        matlab.ui.control.Button
        RotateDown                      matlab.ui.control.Button
        Rotate                          matlab.ui.control.Label
        RotateRight                     matlab.ui.control.Button
        RotateLeft                      matlab.ui.control.Button
        View3D                          matlab.ui.control.UIAxes
        CropPanel                       matlab.ui.container.Panel
        AdjustthresholdSlider           matlab.ui.control.Slider
        AdjustthresholdSliderLabel      matlab.ui.control.Label
        CropButton_3                    matlab.ui.control.Button
        CropButton_2                    matlab.ui.control.Button
        CropButton                      matlab.ui.control.Button
        ZrangeEditField                 matlab.ui.control.EditField
        ZrangeEditFieldLabel            matlab.ui.control.Label
        toZEditField                    matlab.ui.control.EditField
        toZEditFieldLabel               matlab.ui.control.Label
        YrangeEditField                 matlab.ui.control.EditField
        YrangeEditFieldLabel            matlab.ui.control.Label
        toYEditField                    matlab.ui.control.EditField
        toYEditFieldLabel               matlab.ui.control.Label
        toXEditField                    matlab.ui.control.EditField
        toXEditFieldLabel               matlab.ui.control.Label
        XrangeEditField                 matlab.ui.control.EditField
        XrangeEditFieldLabel            matlab.ui.control.Label
        AxesZ                           matlab.ui.control.UIAxes
        AxesY                           matlab.ui.control.UIAxes
        AxesX                           matlab.ui.control.UIAxes
        LoadDataPanel                   matlab.ui.container.Panel
        AutomaticSegmentation           matlab.ui.control.Button
        ScanInfoTable                   matlab.ui.control.Table
        SegmentationDirectoryEditField  matlab.ui.control.EditField
        SegmentationDirectoryEditFieldLabel  matlab.ui.control.Label
        LoadSegmentationDicomsButton    matlab.ui.control.Button
        DataDirectoryEditField          matlab.ui.control.EditField
        DataDirectoryEditFieldLabel     matlab.ui.control.Label
        LoadPARRECDataButton            matlab.ui.control.Button
        Maps                            matlab.ui.container.Tab
        WSSGroup                        matlab.ui.container.Panel
        CalculateWSS                    matlab.ui.control.Button
        WalleffectsCheckBox             matlab.ui.control.CheckBox
        ViscosityPasEditField           matlab.ui.control.EditField
        ViscosityPasLabel               matlab.ui.control.Label
        FramesButtonGroup               matlab.ui.container.ButtonGroup
        PeaksystoleButton               matlab.ui.control.ToggleButton
        AllButton                       matlab.ui.control.ToggleButton
        TimeframeSpinner_2              matlab.ui.control.Spinner
        TimeframeSpinner_2Label         matlab.ui.control.Label
        WSSPlot                         matlab.ui.control.UIAxes
        VelocityVectorGroup             matlab.ui.container.Panel
        maxVelocityVectorEditField      matlab.ui.control.EditField
        velocityVectortoEditFieldLabel  matlab.ui.control.Label
        minVelocityVectorEditField      matlab.ui.control.EditField
        velocityVectorEditFieldLabel    matlab.ui.control.Label
        maxQuiverEditField              matlab.ui.control.EditField
        toXEditFieldLabel_2             matlab.ui.control.Label
        minQuiverEditField              matlab.ui.control.EditField
        quiverscaleEditFieldLabel       matlab.ui.control.Label
        TimeframeSpinner                matlab.ui.control.Spinner
        TimeframeSpinnerLabel           matlab.ui.control.Label
        VelocityVectorsPlot             matlab.ui.control.UIAxes
        FlowandPulseWaveVelocityTab     matlab.ui.container.Tab
        findBestFit_checkbox            matlab.ui.control.CheckBox
        R2Display                       matlab.ui.control.EditField
        PWVDisplayTitle_2               matlab.ui.control.Label
        deleteBranch4                   matlab.ui.control.Button
        BranchDropDown_4                matlab.ui.control.DropDown
        Branch4Label                    matlab.ui.control.Label
        FlipBranch1_4                   matlab.ui.control.CheckBox
        BranchDropDown_3                matlab.ui.control.DropDown
        Branch3Label                    matlab.ui.control.Label
        FlipBranch1_3                   matlab.ui.control.CheckBox
        deleteBranch3                   matlab.ui.control.Button
        AddbranchButton                 matlab.ui.control.Button
        BranchDropDown_2                matlab.ui.control.DropDown
        Branch2Label                    matlab.ui.control.Label
        FlipBranch1_2                   matlab.ui.control.CheckBox
        deleteBranch2                   matlab.ui.control.Button
        BranchDropDown                  matlab.ui.control.DropDown
        BranchDropDownLabel             matlab.ui.control.Label
        FlipBranch1                     matlab.ui.control.CheckBox
        timeResolvedSegCheckbox         matlab.ui.control.CheckBox
        SaveName                        matlab.ui.control.DropDown
        SaveResultsCallback             matlab.ui.control.Button
        SavingTitle                     matlab.ui.control.Label
        PWVDisplay                      matlab.ui.control.EditField
        PWVDisplayTitle                 matlab.ui.control.Label
        PWVType                         matlab.ui.control.DropDown
        CalculatePWV                    matlab.ui.control.Button
        PlotWaveformsButton             matlab.ui.control.Button
        PWVPoints                       matlab.ui.control.EditField
        PWVPointsLabel                  matlab.ui.control.Label
        PWVPointsTitle                  matlab.ui.control.Label
        CheckcenterlinecalculateflowButton  matlab.ui.control.Button
        BranchNumberTitle               matlab.ui.control.Label
        SegmentationAndCenterline       matlab.ui.container.Panel
        ParameterDropDown               matlab.ui.control.DropDown
        ParameterLabel                  matlab.ui.control.Label
        DisplayDistanceCheckbox         matlab.ui.control.CheckBox
        Reset3DviewButton               matlab.ui.control.Button
        View3D_2                        matlab.ui.control.UIAxes
        PWVCalcDisplay                  matlab.ui.control.UIAxes
        WaveformsDisplay                matlab.ui.control.UIAxes
        ResetWorkSpace                  matlab.ui.container.Tab
        CleardataandrestartanalysisButton  matlab.ui.control.Button
    end


    properties (Access = private)
        directory;                  % the data directory
        segDirectory;               % the directory for dicoms from a pre-defined manual segmentation
        v;                          % the 5D velocity matrix (X x Y x Z x t x v)
        nframes;                    % the reconstrucated cardiac time frames
        res;                        % image dimensions in X, Y, and Z
        fov;                        % the acquired field of view, in cm
        pixdim;                     % resolution in X, Y, and Z, in mm
        ori;                        % orientation (1-axial, 2-sagittal, 3-coronal)
        timeres;                    % temporal resolution (per cardiac frame), in ms
        MAG;                        % the 4D magitude matrix (X x Y x Z x t)
        magWeightVel;               % the calculated magnitude weighted velocity
        angio;                      % a maximum intensity PCMRA
        vMean;                      % the mean velocity over time
        VENC;                       % velocity encoding, in mm/s
        segment;                    % the segmentation, updated throughout
        isSegmentationLoaded = 0;   % is the manual segmentation loaded
        isCropped = 0;              % have we performed any cropping?
        mask;                       % the mask
        isRawDataCropped;           % have we cropped the raw data yet?
        aorta_seg;                  % the specific aorta segmentation

        branchList;                 % list of all unique branches following centerline extraction
        branchActual;               % chosen branch for PWV measurements
        area_val;                   % calculated area along branch
        flowPerHeartCycle_vol;      % resulting flow over the cardiac cycle in the aorta_seg voluem
        flowPulsatile_vol;          % pulsatile flow waveforms in the aorta_seg volume

        hpatch1;                    % initial 3D patch for 3D vis
        hpatch2;                    % segmentation 3D patch for 3D vis
        rotAngles;                  % rotation angles used for viewing, can be changed by viewer

        usedBranches                % a list that is built up to determine which branches to perform flow measurements on
        FullBranchDistance          % the full distance vector (in mm)

        R2                          % the r-squared value of the fit for cross-correlation or wavelet PWV measurement
        vvp_xlim                    % velocity vector x limits
        vvp_ylim                    % velocity vector y limits
    end

    methods (Access = private)

        function View3DSegmentation(app)

            cla(app.View3D);
            ss = smooth3(app.segment);

            app.hpatch1 = patch(app.View3D, isosurface(ss,.5),'FaceColor','red','EdgeColor', 'none','FaceAlpha',0.35);
            reducepatch(app.hpatch1 ,0.6);
            if (app.isSegmentationLoaded)
                aa = smooth3(app.aorta_seg);
                hold(app.View3D,'on')
                app.hpatch2 = patch(app.View3D, isosurface(aa,.5),'FaceColor',[0.9 0.9 0.9],'EdgeColor', 'none','FaceAlpha',0.75);
                reducepatch(app.hpatch2,0.6);
            end

            % Make it all look good
            camlight(app.View3D);
            lighting(app.View3D,'gouraud');
            lightangle(app.View3D,0,0);
            view(app.View3D, [0 0 -1]);
            daspect(app.View3D,[1 1 1])
            m_xstart = 1; m_ystart = 1; m_zstart = 1;
            m_xstop = size(app.segment,1); m_ystop = size(app.segment,2); m_zstop = size(app.segment,3);
            xlim(app.View3D,[m_ystart m_ystop]);
            ylim(app.View3D,[m_xstart m_xstop]);
            axis(app.View3D,'off');

            % if rotation angles are non-zero, rotate now
            if sum(app.rotAngles) > 0
                rotate(app.hpatch1,[1 0 0], app.rotAngles(1))
                rotate(app.hpatch1,[0 1 0], app.rotAngles(2))
                if (app.isSegmentationLoaded)
                    rotate(app.hpatch2,[1 0 0], app.rotAngles(1))
                    rotate(app.hpatch2,[0 1 0], app.rotAngles(2))
                end
            end
        end

        function reset3DSegmentationAndCenterline(app)
            % Initialize figure
            colorbar(app.View3D_2,'off')
            cla(app.View3D_2);

            if app.isSegmentationLoaded
                ss = smooth3(app.aorta_seg);
                hpatch = patch(app.View3D_2,isosurface(ss,0.5),'FaceAlpha',0.20);
                reducepatch(hpatch,0.6);
                set(hpatch,'FaceColor',[0.7 0.7 0.7],'EdgeColor', 'none','PickableParts','none');
            else
                ss = smooth3(app.segment);
                hpatch = patch(app.View3D_2,isosurface(ss,0.5),'FaceAlpha',0.20);
                reducepatch(hpatch,0.6);
                set(hpatch,'FaceColor',[0.7 0.7 0.7],'EdgeColor', 'none','PickableParts','none');
            end

            unqBranches = unique(app.branchList(:,4));
            c = lines(length(unqBranches));
            for b = 1:length(unqBranches)
                % extract coordinates for branch and plot
                currBranch = find(app.branchList(:,4) == b);
                hline(b) = line(app.View3D_2, ...
                    app.branchList(currBranch,2),app.branchList(currBranch,1),app.branchList(currBranch,3),...
                    'Color',c(b,:),'Marker','.','MarkerSize',12,'LineStyle','none');
            end

            % make it look good
            axis(app.View3D_2, 'vis3d')
            axis(app.View3D_2, 'off')
            colormap(app.View3D_2,'colorcube')
            camlight(app.View3D_2);
            lighting(app.View3D_2,'gouraud');
            view(app.View3D_2, [0 0 -1]);
            daspect(app.View3D_2,[1 1 1])

            % Put the number labels on the CenterlinePlot
            numString_val = num2str(unqBranches);
            for i = 1:length(unqBranches)
                %find rows where textint = branchList(:,4) and take the mid point
                temp = app.branchList(app.branchList(:,4) == i,1:3);
                textLoc(i,1:3) = temp(round(size(temp,1)/2),:);
            end
            Ntxt = text(app.View3D_2,textLoc(unqBranches,2)+1,textLoc(unqBranches,1)+1,textLoc(unqBranches,3)+1,...
                numString_val,'Color','k','FontSize',20,'FontWeight', 'bold','Margin', 1,...
                'HitTest','off','PickableParts','none');

            % update view angle
            camorbit(app.View3D_2,app.rotAngles(2),app.rotAngles(1),[1 1 0])

            if length(unqBranches) > 1
                app.BranchDropDown.Items = string(unqBranches);
            else
                app.BranchDropDown.Items = {'1'};
            end
        end

        function view3D_wParams(app)
            % indices for flow plotting
            x = app.branchActual(:,1); y = app.branchActual(:,2); z = app.branchActual(:,3);
            index = sub2ind(size(app.aorta_seg),x,y,z);

            %reset figure
            cla(app.View3D_2);
            colorbar(app.View3D_2,'off');

            if app.isSegmentationLoaded
                hpatch = patch(app.View3D_2,isosurface(smooth3(app.aorta_seg),0.5),'FaceAlpha',0.25);
            else
                hpatch = patch(app.View3D_2,isosurface(smooth3(app.segment),0.5),'FaceAlpha',0.25);
            end
            reducepatch(hpatch,0.6);
            set(hpatch,'FaceColor',[0.7 0.7 0.7],'EdgeColor', 'none','PickableParts','none');
            
            % grab parameter from drop-down, and set colorbar description
            switch app.ParameterDropDown.Value
                case 'Total Flow'
                    cdata = app.flowPerHeartCycle_vol(index);
                    cbarText = 'Flow (mL/cycle)';
                case 'Peak Flow'
                    cdata = max(app.flowPulsatile_vol(index,:),[],2);
                    cbarText = 'Peak flow (mL/s)';
                case 'Mean Velocity'
                    cdata = mean(app.flowPulsatile_vol(index,:)./app.area_val,2);
                    cbarText = 'Mean velocity (cm/s)';
                case 'Peak Velocity'
                    cdata = max(app.flowPulsatile_vol(index,:)./app.area_val,[],2);
                    cbarText = 'Peak velocity (cm/s)';
            end

            hSurface = surface(app.View3D_2,'XData',[y(:) y(:)],'YData',[x(:) x(:)],'ZData',[z(:) z(:)],...
                'CData',[cdata(:) cdata(:)],'FaceColor','none','EdgeColor','flat',...
                'Marker','.','MarkerSize',12);

            caxis(app.View3D_2,[min(cdata) max(cdata)]);
            colormap(app.View3D_2,jet)
            cbar = colorbar(app.View3D_2);
            caxis(app.View3D_2,[0 0.95*max(cdata)])
            set(get(cbar,'xlabel'),'string',cbarText,'fontsize',16,'Color','black');
            set(cbar,'FontSize',16,'color','black','Location','west');

            % make it look good
            axis(app.View3D_2, 'vis3d')
            axis(app.View3D_2, 'off')
            camlight(app.View3D_2);
            lighting(app.View3D_2,'gouraud');
            view(app.View3D_2, [0 0 -1]);
            daspect(app.View3D_2,[1 1 1])

            % Put the number labels on the CenterlinePlot
            str = app.PWVPoints.Value;

            if app.DisplayDistanceCheckbox.Value
                out = textscan(str,'%f %f','Delimiter',':');
                [~, minIdx] = min(abs(app.FullBranchDistance-out{1}));
                [~, minIdx2] = min(abs(app.FullBranchDistance-out{2}));
                textint = app.FullBranchDistance(minIdx:5:minIdx2);
                textint2 = minIdx:5:minIdx2;
            else
                eval(['ptRange=[' str '];']);
                textint2 = ptRange(1:5:end); textint = textint2;
            end

            numString_val = num2str(textint);
            numString_val = strsplit(numString_val);

            c = winter(length(textint2));
            for C = 1:length(textint2)
                Ntxt(C) = text(app.View3D_2,app.branchActual(textint2(C),2)-4,app.branchActual(textint2(C),1)-3,app.branchActual(textint2(C),3)+2,numString_val{C},...
                    'Color','k','HorizontalAlignment','right',...
                    'FontSize',20,'FontWeight','Bold','HitTest','off','PickableParts','none');
            end

            % update view angle
            camorbit(app.View3D_2,app.rotAngles(2),app.rotAngles(1),[1 1 0])
            drawnow;
        end

        function plotWaveforms(app)

            % grab waveforms
            x = app.branchActual(:,1); y = app.branchActual(:,2); z = app.branchActual(:,3);
            index = sub2ind(size(app.aorta_seg),x,y,z);
            waveforms = app.flowPulsatile_vol(index,:);

            if contains(app.ParameterDropDown.Value,'Flow')
                plotString = 'Flow (mL/s)';
            else
                waveforms = waveforms./app.area_val;
                plotString = 'Velocity (cm/s)';
            end
            view3D_wParams(app);

            str = app.PWVPoints.Value;

            if app.DisplayDistanceCheckbox.Value
                out = textscan(str,'%f %f','Delimiter',':');
                [~, minIdx] = min(abs(app.FullBranchDistance-out{1}));
                [~, minIdx2] = min(abs(app.FullBranchDistance-out{2}));
                ptRange = minIdx:minIdx2;
            else
                eval(['ptRange=[' str '];']);
            end

            waveforms = waveforms(ptRange,:);

            % plot
            card_time = [0:app.nframes-1]*app.timeres;
            c = winter(size(waveforms,1));
            if size(waveforms,1) > 5
                alpha = linspace(0.8,0.3,size(waveforms,1));
            else
                alpha = 0.8*ones(1,size(waveforms,1));
            end
            c = cat(2,c,alpha');

            colorbar(app.WaveformsDisplay,'off')
            cla(app.WaveformsDisplay);
            hold(app.WaveformsDisplay,'on');
            for ii = 1:size(waveforms,1)
                plot(app.WaveformsDisplay,card_time,waveforms(ii,:)','Color',c(ii,:),...
                    'LineWidth',2)
            end
            xlim(app.WaveformsDisplay,[0 max(card_time)])
            app.WaveformsDisplay.XLabel.String = 'Cardiac Time (ms)';
            app.WaveformsDisplay.YLabel.String = plotString;

            if numel(ptRange) > 1
                colormap(app.WaveformsDisplay,winter);
                cbar = colorbar(app.WaveformsDisplay);
                set(get(cbar,'title'),'string','Point number','fontsize',16,'Color','black');
                set(cbar,'FontName','Calibri','FontSize',10,'color','black');
            end

            % display a max of 5 points on cbar
            if app.DisplayDistanceCheckbox.Value
                set(get(cbar,'title'),'string','Distance (mm)','fontsize',16,'Color','black');
                if size(waveforms,1) < 11
                    cbar.Ticks = linspace(0, 1, size(waveforms,1)) ;
                    cbar.TickLabels = num2cell(app.FullBranchDistance(minIdx:minIdx2));
                else
                    cbar.Ticks = linspace(0, 1, 5) ;
                    cbar.TickLabels = num2cell(round(linspace(double(app.FullBranchDistance(minIdx)),...
                        double((app.FullBranchDistance(minIdx2))),5)));
                end
            else
                if size(waveforms,1) < 11
                    cbar.Ticks = linspace(0, 1, size(waveforms,1)) ;
                    cbar.TickLabels = num2cell(ptRange);
                else
                    cbar.Ticks = linspace(0, 1, 5) ;
                    cbar.TickLabels = num2cell(round(linspace(double(min(ptRange)),double(max(ptRange)),5)));
                end
            end
            hold(app.WaveformsDisplay,'off');
        end

        function maskSz = cropImage(app,img)

            choice = 0;
            while choice == 0
                cropFig = figure(100);
                set(cropFig,'Units','normalized');
                set(cropFig,'Position',[0.0016 0.0481 0.4969 0.8454])
                set(cropFig,'Name','Draw rectangle to crop image')

                % View MIP
                imagesc(img);
                colormap('gray')
                axis equal off
                daspect([1 1 1]);

                h = drawrectangle(gca,'DrawingArea',[1 1 size(img,2)-1 size(img,1)-1]);
                maskSz = h.Position;
                maskSz(1:2) = floor(maskSz(1:2));
                maskSz(3:4) = ceil(maskSz(3:4));
                maskSz(maskSz<1) = 1;
                mask = zeros(size(img));
                mask(maskSz(2):(maskSz(2)+maskSz(4)), maskSz(1):(maskSz(1)+maskSz(3))) = 1;
                imgCropped = img.*mask;

                clf(cropFig);
                imagesc(imgCropped);
                colormap('gray')
                axis equal off
                daspect([1 1 1]);
                set(cropFig,'Name','Cropped image')
                choice = checkCrop;

            end
            % if cancel, reset the mask and img
            if choice == 2
                maskSz = [1 1 size(img,2)-1 size(img,1)-1];
            end
            % update cropped state
            if choice == 1
                app.isCropped = 1;
            end

            close(cropFig)
        end

        function updateMIPs(app, m_xstart, m_xstop, m_ystart, m_ystop, m_zstart, m_zstop)

            % update for ROI drawing
            app.XrangeEditField.Value = num2str(m_xstart);
            app.toXEditField.Value = num2str(m_xstop);

            app.YrangeEditField.Value = num2str(m_ystart);
            app.toYEditField.Value = num2str(m_ystop);

            app.ZrangeEditField.Value = num2str(m_zstart);
            app.toZEditField.Value = num2str(m_zstop);

            % Set mips
            cla(app.AxesX);
            imagesc(app.AxesX,reshape(max(app.angio,[],1),[app.res(2) app.res(3)]));
            set(app.AxesX,'XTickLabel','','YTickLabel','')
            colormap(app.AxesX,'gray')
            axis(app.AxesX,'equal')
            daspect(app.AxesX,[1 1 1]);

            cla(app.AxesY);
            imagesc(app.AxesY,reshape(max(app.angio,[],2),[app.res(1) app.res(3)]));
            set(app.AxesY,'XTickLabel','','YTickLabel','')
            colormap(app.AxesY,'gray')
            axis(app.AxesY,'equal')
            daspect(app.AxesY,[1 1 1]);

            cla(app.AxesZ);
            imagesc(app.AxesZ,reshape(max(app.angio,[],3),[app.res(1) app.res(2)]));
            set(app.AxesZ,'XTickLabel','','YTickLabel','')
            colormap(app.AxesZ,'gray')
            axis(app.AxesZ,'equal')
            daspect(app.AxesZ,[1 1 1]);
        end
        
        function viewVelocityVectors(app)
             %reset figure
            cla(app.VelocityVectorsPlot);
            colorbar(app.VelocityVectorsPlot,'off');
            
            [xcoor_grid,ycoor_grid,zcoor_grid] = meshgrid((1:size(app.aorta_seg,2))*app.pixdim(1),(1:size(app.aorta_seg,1))*app.pixdim(2), ...
                (1:size(app.aorta_seg,3))*app.pixdim(3));
            t = app.TimeframeSpinner.Value;

            vx = app.aorta_seg.*app.v(:,:,:,1,t)/10;
            vy = app.aorta_seg.*app.v(:,:,:,2,t)/10;
            vz = app.aorta_seg.*app.v(:,:,:,3,t)/10;
            vmagn = sqrt(vx.^2 + vy.^2 + vz.^2);
            L = find(app.aorta_seg); 
%             plot3(app.VelocityVectorsPlot,xcoor_grid(L),ycoor_grid(L),zcoor_grid(L),'k.','MarkerSize',1);
            c = [];
            a = [str2double(app.minQuiverEditField.Value) str2double(app.maxQuiverEditField.Value)*max(vmagn(:))/100];
            % note the flipped vx and vy here
            [F,V,C]=quiver3Dpatch(xcoor_grid(L),ycoor_grid(L),zcoor_grid(L),-vy(L),-vx(L),-vz(L),c,a);
            patch(app.VelocityVectorsPlot,'Faces',F,'Vertices',V,'CData',C,'FaceColor','flat','EdgeColor','none','FaceAlpha',0.75);
            caxis(app.VelocityVectorsPlot, [str2double(app.minVelocityVectorEditField.Value) str2double(app.maxVelocityVectorEditField.Value)]);
            colormap(app.VelocityVectorsPlot,jet)
            cbar = colorbar(app.VelocityVectorsPlot);
            set(get(cbar,'xlabel'),'string','Velocity (cm/s)','fontsize',12,'Color','black');
            set(cbar,'FontSize',16,'color','black','Location','west');
            
            % make it look good
            axis(app.VelocityVectorsPlot, 'off','tight')
            view(app.VelocityVectorsPlot, [0 0 -1]);
            daspect(app.VelocityVectorsPlot,[1 1 1])
            if ~isempty(app.vvp_xlim)
                xlim(app.VelocityVectorsPlot,app.vvp_xlim)
                ylim(app.VelocityVectorsPlot,app.vvp_ylim)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add path of subfolder function
            loc = mfilename('fullpath');
            cutoff=1;
            srcdir='';
            if ispc
                tag='\';
            else
                tag='/';
            end
            for n=1:numel(loc)-1
                if strcmpi(loc(end-n),tag)
                    cutoff=numel(loc)-n;
                    break;
                end
            end
            srcdir=loc(1:cutoff);

            addpath(genpath(fullfile(srcdir)))
            drawnow;
        end

        % Button pushed function: LoadPARRECDataButton
        function LoadPARRECDataButtonPushed(app, event)
            clc;
            % from load data
            [app.directory, app.nframes, app.res, app.fov, app.pixdim, app.timeres, app.v, app.MAG, ...
                app.magWeightVel, app.angio, app.vMean, app.VENC, app.ori] = loadPARREC();

            % initialize the mask
            app.mask = ones(size(app.angio));

            % add to info table
            app.ScanInfoTable.Data = cat(2,cellstr([num2str(app.res(1)) ' x ' num2str(app.res(2)) ' x ' num2str(app.res(3))]),...
                cellstr([num2str(round(app.pixdim(1),1)) ' x ' num2str(round(app.pixdim(2),1)) ' x ' num2str(round(app.pixdim(3),1))]),...
                cellstr(num2str(app.timeres)),cellstr(num2str(app.nframes)));

            app.DataDirectoryEditField.Value = app.directory;
            m_xstart = 1; m_ystart = 1; m_zstart = 1;
            m_xstop = app.res(1); m_ystop = app.res(2); m_zstop = app.res(3);

            updateMIPs(app, m_xstart, m_xstop, m_ystart, m_ystop, m_zstart, m_zstop);

            clc;
            disp('View 3D Vasculature')

            normed_MIP = app.angio./max(app.angio(:));
            % fit a Gaussian to non-zero elements to determine threshold
            [muhat,sigmahat] = norm_fit(normed_MIP(:));

            app.segment = zeros(size(app.angio));
            app.segment(normed_MIP>muhat+2*sigmahat) = 1;

            app.segment = bwareaopen(app.segment,round(sum(app.segment(:)).*0.005),6); %The value at the end of the commnad in the minimum area of each segment to keep
            app.segment = imfill(app.segment,'holes'); % Fill in holes created by slow flow on the inside of vessels
            app.segment = single(app.segment);

            % initialize the mask
            app.mask = ones(size(app.angio));

            View3DSegmentation(app);

            % set intial parameters
            app.rotAngles = [0 0];
            app.isRawDataCropped = 0;
        end

        % Button pushed function: CleardataandrestartanalysisButton
        function CleardataandrestartanalysisButtonPushed(app, event)
            delete(app.FlowProcessingUIFigure);  % close the app
            FlowProcessing;                      % re-open it
        end

        % Button pushed function: LoadSegmentationDicomsButton
        function LoadSegmentationDicomsButtonPushed(app, event)

            % if clicked, let the user pick the directory containg the pre-segmented
            % dicoms, load them in and save them, also update 3D view
            app.segDirectory = uigetdir(app.directory, 'Select Segmentation Dicom Folder');
            app.SegmentationDirectoryEditField.Value = app.segDirectory;

            files = dir([app.segDirectory '/*.dcm']);
            % reset the aorta segmentation
            app.aorta_seg = zeros(size(app.angio));
            for ii = 1:numel(files)
                app.aorta_seg(:,:,ii) = dicomread([app.segDirectory '/' files(ii).name]);
            end
            app.isSegmentationLoaded = 1;
            % flip the slice dimension to match our other data
            app.aorta_seg = flip(app.aorta_seg,3);
            % normalize
            app.aorta_seg = single(app.aorta_seg/max(abs(app.aorta_seg(:))));
            app.aorta_seg(find(app.aorta_seg)) = 1;

            View3DSegmentation(app)

        end

        % Button pushed function: UnwrapVelocity
        function UnwrapVelocityButtonPushed(app, event)
            disp('Performing 4D velocity unwrapping...')

            % if raw data is not yet cropped, do it now!
            if ~app.isRawDataCropped
                [x, y, z] = ind2sub(size(app.mask),find(app.mask));
                lx = length(unique(x)); ly = length(unique(y)); lz = length(unique(z));
                maskIdx = find(app.mask);

                % crop velocity
                tempV = reshape(app.v,[prod(app.res),3,app.nframes]);
                tempV = tempV(maskIdx,:,:);
                tempV = reshape(tempV,lx,ly,lz,3,app.nframes);
                app.v = tempV;
                app.vMean = mean(app.v,5);
                clear tempV

                % crop MAG
                tempMAG = reshape(app.MAG,[prod(app.res),app.nframes]);
                tempMAG = tempMAG(maskIdx,:);
                tempMAG = reshape(tempMAG,lx,ly,lz,app.nframes);
                app.MAG = tempMAG;
                clear tempMAG;

                % update magWeightVel and angio
                [app.magWeightVel, app.angio] = calc_angio(app.MAG, app.v, app.VENC);

                % others to crop, segment and aorta_seg
                tempS = app.segment(:);
                tempS = tempS(maskIdx);
                tempS = reshape(tempS,lx,ly,lz);
                app.segment = tempS;
                clear tempS;
                if (app.isSegmentationLoaded)
                    tempS = app.aorta_seg(:);
                    tempS = tempS(maskIdx);
                    tempS = reshape(tempS,lx,ly,lz);
                    app.aorta_seg = tempS;
                    clear tempS;
                end
                app.isRawDataCropped = 1;

                % disable crop buttons
                app.CropButton.Enable = 'off';
                app.CropButton_2.Enable = 'off';
                app.CropButton_3.Enable = 'off';
            end

            % first remove outliers (force everything to +/- VENC)
            V2 = app.v;
            V2(V2 < -app.VENC) = -app.VENC;
            V2(V2 > app.VENC) = app.VENC;
            % now scale V2 to +/- pi for unwrapping
            V2 = V2./app.VENC.*pi;

            % grab velocities
            phi_w_x = squeeze(V2(:,:,:,1,:));
            phi_w_y = squeeze(V2(:,:,:,2,:));
            phi_w_z = squeeze(V2(:,:,:,3,:));

            % perform unwrapping
            if (size(phi_w_x,4))==1
                phi_w_x_unwrapped = phi_w_x + 2*pi .* double(unwrap_3D(phi_w_x));
                phi_w_y_unwrapped = phi_w_y + 2*pi .* double(unwrap_3D(phi_w_y));
                phi_w_z_unwrapped = phi_w_z + 2*pi .* double(unwrap_3D(phi_w_z));
            else
                phi_w_x_unwrapped = phi_w_x + 2*pi .* double(unwrap_4D(phi_w_x));
                phi_w_y_unwrapped = phi_w_y + 2*pi .* double(unwrap_4D(phi_w_y));
                phi_w_z_unwrapped = phi_w_z + 2*pi .* double(unwrap_4D(phi_w_z));
            end

            % find the absolute maximum phi value across unwrapped data
            max_phi = max(abs(phi_w_x_unwrapped(:)));
            if max(abs(phi_w_y_unwrapped(:))) > max_phi
                max_phi = max(abs(phi_w_y_unwrapped(:)));
            end
            if max(abs(phi_w_z_unwrapped(:))) > max_phi
                max_phi = max(abs(phi_w_z_unwrapped(:)));
            end

            % inform of new maximum velocity
            new_venc = max_phi/pi*app.VENC;
            disp('Unwrapping finished')
            msgbox(sprintf('New maximum velocity = %4d cm/s\n',round(new_venc/10)),'Unwrapping complete','replace');
            app.VENC = new_venc;

            % rescale images based on 'new' venc
            app.v(:,:,:,1,:) = phi_w_x_unwrapped./pi*app.VENC;
            app.v(:,:,:,2,:) = phi_w_y_unwrapped./pi*app.VENC;
            app.v(:,:,:,3,:) = phi_w_z_unwrapped./pi*app.VENC;
            app.vMean = mean(app.v,5);

            % we only do unwrapping once, so disable after completion
            app.UnwrapVelocity.Enable = 'off';

            % update segmentation
            value = app.AdjustthresholdSlider.Value;
            [muhat,sigmahat] = norm_fit(app.angio(:));

            app.segment = zeros(size(app.angio));
            app.segment(app.angio>muhat+value*sigmahat) = 1;
            app.segment = bwareaopen(app.segment,round(sum(app.segment(:)).*0.005),6); %The value at the end of the commnad in the minimum area of each segment to keep
            app.segment = imfill(app.segment,'holes'); % Fill in holes created by slow flow on the inside of vessels
            app.segment = single(app.segment);

            % update 3D isosurface view
            View3DSegmentation(app)
        end

        % Button pushed function: MapsPushButton
        function MapsPushButtonPushed(app, event)
            % if raw data is not yet cropped, do it now!
            if ~app.isRawDataCropped
                [x, y, z] = ind2sub(size(app.mask),find(app.mask));
                lx = length(unique(x)); ly = length(unique(y)); lz = length(unique(z));
                maskIdx = find(app.mask);

                % crop velocity
                tempV = reshape(app.v,[prod(app.res),3,app.nframes]);
                tempV = tempV(maskIdx,:,:);
                tempV = reshape(tempV,lx,ly,lz,3,app.nframes);
                app.v = tempV;
                app.vMean = mean(app.v,5);
                clear tempV

                % crop MAG
                tempMAG = reshape(app.MAG,[prod(app.res),app.nframes]);
                tempMAG = tempMAG(maskIdx,:);
                tempMAG = reshape(tempMAG,lx,ly,lz,app.nframes);
                app.MAG = tempMAG;
                clear tempMAG;

                % update magWeightVel and angio
                [app.magWeightVel, app.angio] = calc_angio(app.MAG, app.v, app.VENC);

                % others to crop, segment and aorta_seg
                tempS = app.segment(:);
                tempS = tempS(maskIdx);
                tempS = reshape(tempS,lx,ly,lz);
                app.segment = tempS;
                clear tempS;
                if (app.isSegmentationLoaded)
                    tempS = app.aorta_seg(:);
                    tempS = tempS(maskIdx);
                    tempS = reshape(tempS,lx,ly,lz);
                    app.aorta_seg = tempS;
                    clear tempS;
                else
                    app.aorta_seg = app.segment;
                end
                app.isRawDataCropped = 1;

                % disable crop buttons
                app.CropButton.Enable = 'off';
                app.CropButton_2.Enable = 'off';
                app.CropButton_3.Enable = 'off';
            end
            
            % switch to the correct tab and enable fields
            app.TabGroup.SelectedTab = app.Maps;
            app.TimeframeSpinnerLabel.Enable = 'on';
            app.TimeframeSpinner.Enable = 'on';
%             app.SliceSpinnerLabel.Enable = 'on';
%             app.SliceSpinner.Enable = 'on';
            app.quiverscaleEditFieldLabel.Enable = 'on';
            app.minQuiverEditField.Enable = 'on';
            app.toXEditFieldLabel_2.Enable = 'on';
            app.maxQuiverEditField.Enable = 'on';
            app.minVelocityVectorEditField.Enable = 'on';
            app.velocityVectortoEditFieldLabel.Enable = 'on';
            app.velocityVectorEditFieldLabel.Enable = 'on';
            app.maxVelocityVectorEditField.Enable = 'on';
            app.maxVelocityVectorEditField.Value = num2str(round(app.VENC/10));
            app.TimeframeSpinner.Limits = [1,app.nframes];
%             app.SliceSpinner.Value = round(size(app.angio,3)/2);
            
            % find peak velocity frame
            Vmag = repmat(app.aorta_seg,[1 1 1 app.nframes]).*squeeze(sqrt(sum(app.v.^2,4)));
            idx = find(app.aorta_seg);
            for t = 1:app.nframes
                tmp = Vmag(:,:,:,t);
                mean_velo(t) = mean(tmp(idx));
            end

            [~,time_peak] = find(mean_velo==max(mean_velo));
            disp(['Peak systole is timestep ' num2str(time_peak)])
            app.TimeframeSpinner.Value = time_peak;

            % view vectors
            viewVelocityVectors(app);
            % set initial limits for later plotting
            app.vvp_xlim = app.VelocityVectorsPlot.XLim;
            app.vvp_ylim = app.VelocityVectorsPlot.YLim;
            
        end

        % Button pushed function: PulseWaveVelocityPushButton
        function PulseWaveVelocityPushButtonButtonPushed(app, event)

            % if raw data is not yet cropped, do it now!
            if ~app.isRawDataCropped
                [x, y, z] = ind2sub(size(app.mask),find(app.mask));
                lx = length(unique(x)); ly = length(unique(y)); lz = length(unique(z));
                maskIdx = find(app.mask);

                % crop velocity
                tempV = reshape(app.v,[prod(app.res),3,app.nframes]);
                tempV = tempV(maskIdx,:,:);
                tempV = reshape(tempV,lx,ly,lz,3,app.nframes);
                app.v = tempV;
                app.vMean = mean(app.v,5);
                clear tempV

                % crop MAG
                tempMAG = reshape(app.MAG,[prod(app.res),app.nframes]);
                tempMAG = tempMAG(maskIdx,:);
                tempMAG = reshape(tempMAG,lx,ly,lz,app.nframes);
                app.MAG = tempMAG;
                clear tempMAG;

                % update magWeightVel and angio
                [app.magWeightVel, app.angio] = calc_angio(app.MAG, app.v, app.VENC);

                % others to crop, segment and aorta_seg
                tempS = app.segment(:);
                tempS = tempS(maskIdx);
                tempS = reshape(tempS,lx,ly,lz);
                app.segment = tempS;
                clear tempS;
                if (app.isSegmentationLoaded)
                    tempS = app.aorta_seg(:);
                    tempS = tempS(maskIdx);
                    tempS = reshape(tempS,lx,ly,lz);
                    app.aorta_seg = tempS;
                    clear tempS;
                end
                app.isRawDataCropped = 1;

                % disable crop buttons
                app.CropButton.Enable = 'off';
                app.CropButton_2.Enable = 'off';
                app.CropButton_3.Enable = 'off';

            end

            % these are hard-coded for now
            sortingCriteria = 3;
            spurLength = 15;

            se = strel('sphere',1);
            if app.isSegmentationLoaded
                ss = imerode(app.aorta_seg,se);
            else
                ss = imerode(app.segment,se);
            end
            [~,~, app.branchList, ~] = feature_extraction( ...
                sortingCriteria, spurLength, app.vMean, ss);

            reset3DSegmentationAndCenterline(app);
            app.TabGroup.SelectedTab = app.FlowandPulseWaveVelocityTab;
        end

        % Button pushed function: CheckcenterlinecalculateflowButton
        function CheckcenterlinecalculateflowButtonPushed(app, event)
            % Grab the branches from user input, then perform aorta segmentation, check
            % the points/segmentation is correct, and calculate flow waveforms

            % parse points
            ptRange = str2double(app.BranchDropDown.Value);

            if strcmp(app.Branch2Label.Visible, 'on')
                ptRange = cat(1,ptRange,str2double(app.BranchDropDown_2.Value));
            end
            if strcmp(app.Branch3Label.Visible, 'on')
                ptRange = cat(1,ptRange,str2double(app.BranchDropDown_3.Value));
            end
            if strcmp(app.Branch4Label.Visible, 'on')
                ptRange = cat(1,ptRange,str2double(app.BranchDropDown_4.Value));
            end

            idx = [];
            %  extract branches, if flip checkbox on, flip the centerline
            for b = 1:numel(ptRange)
                tmpIdx = find(app.branchList(:,4)==ptRange(b));
                switch b
                    case 1
                        if app.FlipBranch1.Value
                            tmpIdx = flip(tmpIdx,1);
                        end
                    case 2
                        if app.FlipBranch1_2.Value
                            tmpIdx = flip(tmpIdx,1);
                        end
                    case 3
                        if app.FlipBranch1_3.Value
                            tmpIdx = flip(tmpIdx,1);
                        end
                    case 4
                        if app.FlipBranch1_4.Value
                            tmpIdx = flip(tmpIdx,1);
                        end
                end
                idx = cat(1,idx,tmpIdx);
            end
            app.branchActual = app.branchList(idx,1:3);

            % smooth the centerline for visualization and measurements
            windowWidth = 5;    % the smoothing window
            polynomialOrder = 1;
            xsg=sgolayfilt(app.branchActual(:,1),polynomialOrder, windowWidth);
            ysg=sgolayfilt(app.branchActual(:,2),polynomialOrder, windowWidth);
            zsg=sgolayfilt(app.branchActual(:,3),polynomialOrder, windowWidth);
            app.branchActual = round([xsg,ysg,zsg]);

            reset3DSegmentationAndCenterline(app);
            hline2 = line(app.View3D_2,app.branchActual(:,2),app.branchActual(:,1),app.branchActual(:,3),...
                'Color','g','Marker','*','MarkerSize',12,'LineStyle','none');

            choice = choosedialog;
            %             checkCount = checkCount+1;

            if choice

                clc;
                % now we've found the centerline:
                % 1. calculate aorta segmentation (if not already available
                % 2. perform non-rigid registration to get time-resolved aortic
                % segmentation
                % 3. calculate flow

                % calculate aorta segmentation, if not already available
                if app.isSegmentationLoaded == 0   % create a new aorta_seg
                    x = app.branchActual(:,1); y = app.branchActual(:,2); z = app.branchActual(:,3);
                    index = sub2ind(size(app.segment),x,y,z);
                    g = zeros(size(app.segment));
                    g(index) = 1;

                    se = strel('sphere',4);
                    gg = imdilate(g,se);

                    app.aorta_seg = smooth3(gg);
                end

                % perform 3D nonrigid registration of segmentation and apply
                % displacement field to aorta_seg

                % create a mask based on the extent of aorta_seg, this helps in the
                % registration step (masking out erroneous fat signal and foldover),
                % keep up to 5 voxels on each side
                [x,y,z] = ind2sub(size(app.aorta_seg),find(app.aorta_seg));
                mask = zeros(size(app.aorta_seg));
                x1 = min(x)-5; if x1<1; x1=1; end
                x2 = max(x)+5; if x2>size(app.aorta_seg,1); x2=size(app.aorta_seg,1); end
                y1 = min(y)-5; if y1<1; y1=1; end
                y2 = max(y)+5; if y2>size(app.aorta_seg,2); y2=size(app.aorta_seg,2); end
                z1 = min(z)-5; if z1<1; z1=1; end
                z2 = max(z)+5; if z2>size(app.aorta_seg,3); z2=size(app.aorta_seg,3); end
                mask(x1:x2,y1:y2,z1:z2) = 1;

                aortaSeg_timeResolved = zeros([size(app.angio) app.nframes]);
                fixed = mask.*app.angio; fixed = fixed/max(fixed(:));
                for j = 1:app.nframes

                    if app.timeResolvedSegCheckbox.Value == 1
                        % progress bar
                        h = waitbar(0, sprintf('Registering aorta segmentation time frames...'));
                        moving = app.magWeightVel(:,:,:,j);
                        moving = mask.*moving; moving = moving/max(moving(:));
                        moving = imhistmatch(moving,fixed);
                        % calculate structual similarity, if low, skip registration and use
                        % default segmentation
                        ssimval = ssim(moving,fixed);
                        if j > app.nframes/2 && j < 3/4*app.nframes
                            smoothFactor = 3.5;
                        elseif j >= 3/4*app.nframes
                            smoothFactor = 4.5;
                        else
                            smoothFactor = 2;
                        end
                        [MOVINGREG.DisplacementField,MOVINGREG.RegisteredImage] = imregdemons(moving,fixed,[100 50 25 10],'AccumulatedFieldSmoothing',smoothFactor,'PyramidLevels',4,'DisplayWaitBar',false);
                        % apply transforms directly to aortaSeg
                        aortaSeg_timeResolved(:,:,:,j) = imwarp(app.aorta_seg,-MOVINGREG.DisplacementField);
                        waitbar (j/app.nframes, h)
                    else
                        if app.isSegmentationLoaded
                            aortaSeg_timeResolved(:,:,:,j) = app.aorta_seg;
                        else
                            aortaSeg_timeResolved(:,:,:,j) = app.segment;
                        end
                    end
                    if exist('h')
                        close(h);
                    end
                end

                app.branchActual = flipud(app.branchActual);

                % Calculate flow over whole aorta
                displayWaitBar = true;
                [app.flowPerHeartCycle_vol, app.flowPulsatile_vol, segment1, app.area_val] = ...
                    params_timeResolved(app.branchActual, app.angio, app.MAG, app.v, app.nframes, app.pixdim, aortaSeg_timeResolved, app.isSegmentationLoaded,...
                    app.timeResolvedSegCheckbox.Value, displayWaitBar);

                % flows are calculated, so we can enable 'Display Distance'
                % and 'Parameter Drop Down'
                app.DisplayDistanceCheckbox.Enable = true;
                app.DisplayDistanceCheckbox.Visible = 'on';
                app.ParameterDropDown.Enable = true;
                app.ParameterDropDown.Visible = 'on';
                app.ParameterLabel.Visible = 'on';
                % calculate distance
                branch = app.branchActual;
                vox = mean(app.pixdim);
                for i=2:size(branch,1)
                    dist_vec(i-1) = norm(branch(i,:)-branch(i-1,:))*vox;
                end
                app.FullBranchDistance = round([0 cumsum(dist_vec)],1);
                if app.DisplayDistanceCheckbox.Value
                    % skip first and last 4 points, immediately calculate PWV
                    app.PWVPoints.Value = [num2str(app.FullBranchDistance(5)) ': ' ...
                        num2str(app.FullBranchDistance(length(branch)-4))];
                    app.PWVPointsLabel.Text = ['PWV dist (mm) [' num2str(app.FullBranchDistance(5)) ':' ...
                        num2str(app.FullBranchDistance(length(branch)-4)) ']'];
                else
                    % skip first and last 4 points, immediately calculate PWV
                    app.PWVPoints.Value = ['5: ' num2str(length(app.branchActual)-4)];
                    app.PWVPointsLabel.Text = ['PWV Points [5:' num2str(length(app.branchActual)-4) ']'];
                end
                CalculatePWVButtonPushed(app, event);

                % view the flows at each centerline point, and plot the waveforms
                view3D_wParams(app);
                plotWaveforms(app);
            else
                msgbox('Change selected branch numbers')
                reset3DSegmentationAndCenterline(app);
                return;
            end
        end

        % Button pushed function: Reset3DviewButton
        function Reset3DviewButtonPushed(app, event)
            reset3DSegmentationAndCenterline(app);
        end

        % Button pushed function: PlotWaveformsButton
        function PlotWaveformsButtonPushed(app, event)
            plotWaveforms(app);
        end

        % Button pushed function: CalculatePWV
        function CalculatePWVButtonPushed(app, event)
            % grab waveforms
            x = app.branchActual(:,1); y = app.branchActual(:,2); z = app.branchActual(:,3);
            index = sub2ind(size(app.segment),x,y,z);
            waveforms = app.flowPulsatile_vol(index,:);

            % parse points
            str = app.PWVPoints.Value;
            if app.DisplayDistanceCheckbox.Value
                % convert distance to points
                out = textscan(str,'%f %f','Delimiter',':');
                [~, minIdx] = min(abs(app.FullBranchDistance-out{1}));
                [~, minIdx2] = min(abs(app.FullBranchDistance-out{2}));
                ptRange = minIdx:minIdx2;
            else
                eval(['ptRange=[' str '];']);
            end
            waveforms = waveforms(ptRange,:);

            % grab PWV calc type: 1 is cross correlation, 2 is Wavelet, 3 is
            % maximum likelihood estimation
            % can be updated with update to calc_pwv
            switch app.PWVType.Value
                case 'Cross-correlation'
                    PWVcalctype = 1;
                    if numel(ptRange) < 3
                        errordlg('Need at least 3 points for cross-correlation PWV calculation')
                        return;
                    end
                case 'Wavelet'
                    PWVcalctype = 2;
                    if numel(ptRange) < 3
                        errordlg('Need at least 3 points for Wavelet PWV calculation')
                        return;
                    end
                case 'Maximum likelihood'
                    PWVcalctype = 3;
            end

            % pass data into calc_pwv
            branch = app.branchActual(ptRange,:);
            vox = mean(app.pixdim);
            for i=2:size(branch,1)
                dist_vec(i-1) = norm(branch(i,:)-branch(i-1,:))*vox;
            end
            % total distance along centerline
            if PWVcalctype == 3 % max likelihood needs the 'zero' distance
                dist_total = [0 cumsum(dist_vec)];
            else
                dist_total = cumsum(dist_vec);
            end

            if PWVcalctype < 3

                % calculate PWV using the delay times using xcorrelation
                [D,fitObject, R, dist_total2] = calc_pwv(waveforms,dist_total,app.timeres,PWVcalctype,app.area_val(ptRange));
                % the PWV, 1/slope of fit
                PWV = 1/fitObject(1);
                % the R2 of the fit
                app.R2 = R(2).*R(2);

                y1 = polyval(fitObject,dist_total2,'r');

                % plot and display slope
                cla(app.PWVCalcDisplay)
                scatter(app.PWVCalcDisplay,dist_total2,D,'.k','SizeData',75);
                hold(app.PWVCalcDisplay,'on');
                plot(app.PWVCalcDisplay,dist_total2,y1,'b','LineWidth',2);
                legend(app.PWVCalcDisplay,'delays','linear fit','Location','Northwest')
                app.PWVCalcDisplay.XLabel.String = 'distance (mm)';
                switch PWVcalctype
                    case 1
                        str = 'cross-corr delay (ms)';
                        app.PWVCalcDisplay.YLim = [0 max(D)+1];
                    case 2
                        str = 'wavelet delay (ms)';
                        app.PWVCalcDisplay.YLim = [0 max(D)+1];
                end
                app.PWVCalcDisplay.YLabel.String = str;

            else % directly calculate PWV using maximum likelihood

                % distance in meters
                d = dist_total'/1000;

                % time res in seconds
                tRes = app.timeres/1000;

                % to follow Anders/Cecilia code, convert to velocity
                pwv0 = 10; %initial guess
                tempArea = mean(app.area_val(ptRange,:),2);
                v = detrend(waveforms'./repmat(tempArea,1,app.nframes)','constant')'; % converted to average velocity
                scaling = 1./std(v(:,:),[],2);
                vsc = v.*repmat(scaling,1,app.nframes);
                mean_flow = mean(vsc);
                inParams=[randn(1,app.nframes), pwv0]; %
                fun1=@(inParams)PWVest3_share(inParams,d,vsc,tRes,tempArea./(scaling.^2));
                options = optimset('Display','iter', 'TolCon', 1e-7, 'TolX', 1e-7, 'TolFun', 1e-7,'DiffMinChange', 1e-3);
                [params,exitflag,output] = fminunc(fun1,inParams, options);


                %                 % set waveMat and normalize to have zero mean and unit std
                %                 waveMat = waveforms;
                %                 [waveMat, ~, scalingFactor] = zscore(waveMat');
                %                 waveMat = waveMat';

                %                 % scaling from cross section area and zscore scaling
                %
                %                 w = tempArea'./scalingFactor.^2;
                %                 %                 w = ones(size(waveMat,1),1);

                %                 fun1=@(inParams)PWVest3_share(inParams,d,waveMat,tRes,w);
                %                 pwv0 = 10; %initial guess of pwv
                %                 mean_flow = mean(waveMat); %initial guess of waveform
                %                 initialGuess=[mean_flow, pwv0];

                %                 [params,exitflag,output] = fminunc(fun1,initialGuess, options);%,options1);
                PWV = params(end); % have a look at the PWV

                card_time = [0:app.nframes-1]*app.timeres;
                cla(app.PWVCalcDisplay)
                plot(app.PWVCalcDisplay,card_time,mean_flow,'k','LineWidth',2);
                hold(app.PWVCalcDisplay,'on');
                plot(app.PWVCalcDisplay,card_time,params(1:end-1),'b','LineWidth',2);
                app.PWVCalcDisplay.YLim = [min([params(1:end-1) mean_flow]) ...
                    max([params(1:end-1) mean_flow])];
                legend(app.PWVCalcDisplay,'initial guess','final waveform','Location','Northeast')
                app.PWVCalcDisplay.XLabel.String = 'cardiac time (ms)';
                app.PWVCalcDisplay.YLabel.String = 'velocity wave (a.u.)';
                app.R2 = [];        % no R2 needed for the method

            end
            app.PWVDisplay.Value = sprintf('%1.2f', PWV);
            app.R2Display.Value = sprintf('%0.3f', app.R2);

            % if 'find best fit' checked, repeat PWV measurements across the
            % 20 closest starting points
            if app.findBestFit_checkbox.Value && PWVcalctype < 3
                nChks = 20;
                Chks = min(ptRange)-nChks/2:min(ptRange)+nChks/2; Chks(Chks<1) = [];
                count = 0;
                for chk = Chks
                    count = count+1;
                    tmpPtRange = chk:max(ptRange);
                    waveforms = app.flowPulsatile_vol(index,:);
                    % pass data into calc_pwv
                    branch = app.branchActual(tmpPtRange,:);
                    clear dist_vec
                    for i=2:size(branch,1)
                        dist_vec(i-1) = norm(branch(i,:)-branch(i-1,:))*vox;
                    end
                    [~,fitObject, R, ~] = calc_pwv(waveforms(tmpPtRange,:),cumsum(dist_vec),app.timeres,PWVcalctype,app.area_val(ptRange));
                    tempPWV(count) = 1/fitObject(1);
                    tempR2(count) = R(2).*R(2);
                end

                [R2,I] = max(tempR2);
                PWV = tempPWV(I);
            end

            view3D_wParams(app);
            plotWaveforms(app);

            if app.findBestFit_checkbox.Value && PWVcalctype < 3
                % inform of the best fit
                msgbox(sprintf('Best fit found for starting point=%i; R^2=%0.3f; PWV=%1.2f m/s', Chks(I), R2, PWV), 'Best fit','replace')
            end

        end

        % Button pushed function: SaveResultsCallback
        function SaveResultsCallbackButtonPushed(app, event)

            savePrefix = app.SaveName.Value;
            saveFolder = fullfile(app.directory, 'PWV_results'); mkdir(saveFolder);
            saveName =  fullfile(saveFolder, savePrefix);

            % is DisplayDistance, convert to true points for saving
            if app.DisplayDistanceCheckbox.Value
                % convert distance to points
                str = app.PWVPoints.Value;
                out = textscan(str,'%f %f','Delimiter',':');
                [~, minIdx] = min(abs(app.FullBranchDistance-out{1}));
                [~, minIdx2] = min(abs(app.FullBranchDistance-out{2}));
                PWV = table(str2double(app.PWVDisplay.Value),{app.R2},{[num2str(minIdx) ':' num2str(minIdx2)]});
            else
                PWV = table(str2double(app.PWVDisplay.Value),{app.R2},{app.PWVPoints.Value});
            end

            PWV.Properties.VariableNames = {'PWV','R2','Save_Points'};
            writetable(PWV,[saveName '.xlsx']);

            % grab and save image
            robot = java.awt.Robot();
            temp = app.FlowProcessingUIFigure.Position; % returns position as [left bottom width height]
            allMonPos = get(0,'MonitorPositions');
            curMon = find(temp(1)<(allMonPos(:,1)+allMonPos(:,3)),1,'first');
            curMonHeight = allMonPos(curMon,4)+1;
            pos = [temp(1) curMonHeight-(temp(2)+temp(4)) temp(3)-1 temp(4)]; % [left top width height].... UL X, UL Y, width, height
            rect = java.awt.Rectangle(pos(1),pos(2),pos(3),pos(4));
            cap = robot.createScreenCapture(rect);
            % Convert to an RGB image
            rgb = typecast(cap.getRGB(0,0,cap.getWidth,cap.getHeight,[],0,cap.getWidth),'uint8');
            imgData = zeros(cap.getHeight,cap.getWidth,3,'uint8');
            imgData(:,:,1) = reshape(rgb(3:4:end),cap.getWidth,[])';
            imgData(:,:,2) = reshape(rgb(2:4:end),cap.getWidth,[])';
            imgData(:,:,3) = reshape(rgb(1:4:end),cap.getWidth,[])';
            imwrite(imgData, [saveName '.tiff']);

            % save the processing information in a results struct
            results = [];
            results.directory = app.directory;
            results.segDirectory = app.segDirectory;
            results.branchActual = app.branchActual;
            results.segment = app.segment;
            results.flowPulsatile = app.flowPulsatile_vol;
            results.flowPerHeartCycle_vol = app.flowPerHeartCycle_vol;
            results.mask = app.mask;
            results.aorta_seg = app.aorta_seg;
            %             results.MAG = app.MAG;
            %             results.v = app.v;
            results.nframes = app.nframes;
            results.fov = app.fov;
            results.pixdim = app.pixdim;
            results.timeres = app.timeres;
            results.angio = app.angio;
            results.VENC = app.VENC;
            save([saveName '_results.mat'],'results','-v7.3')

            % inform of the saving
            msgbox(['results saved to ' saveName '.xlsx'], 'Saving complete','replace')
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            img = app.AxesX.Children(length(app.AxesX.Children),1).CData;
            maskSz = cropImage(app,img);
            m_xstart = str2double(app.XrangeEditField.Value);
            m_xstop = str2double(app.toXEditField.Value);
            m_ystart = maskSz(2);m_ystop = maskSz(2)+maskSz(4);
            m_zstart = maskSz(1);m_zstop = maskSz(1)+maskSz(3);

            tempMask = zeros(size(img));tempMask(m_ystart:m_ystop,m_zstart:m_zstop) = 1;
            app.mask = app.mask.*repmat(permute(tempMask,[3 1 2]),[size(app.mask,1) 1 1]);

            % update angio and segmentation
            app.angio = app.angio.*app.mask;
            app.segment = app.segment.*app.mask;
            if (app.isSegmentationLoaded)
                app.aorta_seg = app.aorta_seg.*app.mask;
            else
                app.aorta_seg = app.segment;
            end

            updateMIPs(app, m_xstart, m_xstop, m_ystart, m_ystop, m_zstart, m_zstop);
            View3DSegmentation(app);

        end

        % Button pushed function: CropButton_2
        function CropButton_2Pushed(app, event)
            img = app.AxesY.Children(length(app.AxesY.Children),1).CData;
            maskSz = cropImage(app,img);

            m_xstart = maskSz(2);m_xstop = maskSz(2)+maskSz(4);
            m_ystart = str2double(app.YrangeEditField.Value);
            m_ystop = str2double(app.toYEditField.Value);
            m_zstart = maskSz(1);m_zstop = maskSz(1)+maskSz(3);

            tempMask = zeros(size(img));tempMask(m_xstart:m_xstop,m_zstart:m_zstop) = 1;
            app.mask = app.mask.*repmat(permute(tempMask,[1 3 2]),[1 size(app.mask,2) 1]);

            % update angio and segmentation
            app.angio = app.angio.*app.mask;
            app.segment = app.segment.*app.mask;
            if (app.isSegmentationLoaded)
                app.aorta_seg = app.aorta_seg.*app.mask;
            else
                app.aorta_seg = app.segment;
            end

            updateMIPs(app, m_xstart, m_xstop, m_ystart, m_ystop, m_zstart, m_zstop);
            View3DSegmentation(app);

        end

        % Button pushed function: CropButton_3
        function CropButton_3Pushed(app, event)
            img = app.AxesZ.Children(length(app.AxesZ.Children),1).CData;
            maskSz = cropImage(app,img);
            m_xstart = maskSz(2);m_xstop = maskSz(2)+maskSz(4);
            m_ystart = maskSz(1);m_ystop = maskSz(1)+maskSz(3);
            m_zstart = str2double(app.ZrangeEditField.Value);
            m_zstop = str2double(app.toZEditField.Value);

            tempMask = zeros(size(img));tempMask(m_xstart:m_xstop,m_ystart:m_ystop) = 1;
            app.mask = app.mask.*repmat(tempMask,[1 1 size(app.mask,3)]);

            % update angio and segmentation
            app.angio = app.angio.*app.mask;
            app.segment = app.segment.*app.mask;
            if (app.isSegmentationLoaded)
                app.aorta_seg = app.aorta_seg.*app.mask;
            else
                app.aorta_seg = app.segment;
            end

            updateMIPs(app, m_xstart, m_xstop, m_ystart, m_ystop, m_zstart, m_zstop);
            View3DSegmentation(app);
        end

        % Button pushed function: RotateLeft
        function RotateLeftButtonPushed(app, event)
            rotate(app.hpatch1,[0 1 0],10)
            if (app.isSegmentationLoaded)
                rotate(app.hpatch2,[0 1 0],10)
            end
            % update rotate angles
            app.rotAngles = [app.rotAngles(1) app.rotAngles(2)+10];
        end

        % Button pushed function: RotateRight
        function RotateRightButtonPushed(app, event)
            rotate(app.hpatch1,[0 1 0],-10)
            if (app.isSegmentationLoaded)
                rotate(app.hpatch2,[0 1 0],-10)
            end
            % update rotate angles
            app.rotAngles = [app.rotAngles(1) app.rotAngles(2)-10];
        end

        % Button pushed function: RotateDown
        function RotateDownButtonPushed(app, event)
            rotate(app.hpatch1,[1 0 0],10)
            if (app.isSegmentationLoaded)
                rotate(app.hpatch2,[1 0 0],10)
            end
            % update rotate angles
            app.rotAngles = [app.rotAngles(1)+10 app.rotAngles(2)];
        end

        % Button pushed function: RotateUp
        function RotateUpButtonPushed(app, event)
            rotate(app.hpatch1,[1 0 0],-10)
            if (app.isSegmentationLoaded)
                rotate(app.hpatch2,[1 0 0],-10)
            end
            % update rotate angles
            app.rotAngles = [app.rotAngles(1)-10 app.rotAngles(2)];
        end

        % Button pushed function: AddbranchButton
        function AddbranchButtonPushed(app, event)
            % turn on branch 2 if not on
            if strcmp(app.Branch2Label.Visible, 'off')
                app.Branch2Label.Visible = 'on';
                app.BranchDropDown_2.Visible = 'on';
                app.FlipBranch1_2.Visible = 'on';
                app.deleteBranch2.Visible = 'on';
                % update items to remove branch 1
                currBranches = app.BranchDropDown.Value;
                idx=strcmp(currBranches,app.BranchDropDown.Items);
                app.BranchDropDown_2.Items = app.BranchDropDown.Items(~idx);
                % turn on branch 3
            elseif strcmp(app.Branch3Label.Visible,'off')
                app.Branch3Label.Visible = 'on';
                app.BranchDropDown_3.Visible = 'on';
                app.FlipBranch1_3.Visible = 'on';
                app.deleteBranch3.Visible = 'on';
                app.deleteBranch2.Visible = 'off';
                % update items to remove branch 1 and branch 2
                idx=strcmp(app.BranchDropDown.Value,app.BranchDropDown.Items) | ...
                    strcmp(app.BranchDropDown_2.Value,app.BranchDropDown.Items);
                app.BranchDropDown_3.Items = app.BranchDropDown.Items(~idx);
                % turn on branch 4
            elseif strcmp(app.Branch4Label.Visible,'off')
                app.Branch4Label.Visible = 'on';
                app.BranchDropDown_4.Visible = 'on';
                app.FlipBranch1_4.Visible = 'on';
                app.deleteBranch4.Visible = 'on';
                app.deleteBranch3.Visible = 'off';
                % update items to remove branch 1 and branch 2
                idx=strcmp(app.BranchDropDown.Value,app.BranchDropDown.Items) | ...
                    strcmp(app.BranchDropDown_2.Value,app.BranchDropDown.Items) | ...
                    strcmp(app.BranchDropDown_3.Value,app.BranchDropDown.Items);
                app.BranchDropDown_4.Items = app.BranchDropDown.Items(~idx);
            end
        end

        % Value changed function: AdjustthresholdSlider
        function AdjustthresholdSliderValueChanged(app, event)
            value = app.AdjustthresholdSlider.Value;

            % update segmentation
            [muhat,sigmahat] = norm_fit(app.angio(:));

            app.segment = zeros(size(app.angio));
            app.segment(app.angio>muhat+value*sigmahat) = 1;
            app.segment = bwareaopen(app.segment,round(sum(app.segment(:)).*0.005),6); %The value at the end of the commnad in the minimum area of each segment to keep
            app.segment = imfill(app.segment,'holes'); % Fill in holes created by slow flow on the inside of vessels
            app.segment = single(app.segment);

            % update 3D isosurface view
            View3DSegmentation(app)
        end

        % Button pushed function: deleteBranch2
        function deleteBranch2ButtonPushed(app, event)
            app.Branch2Label.Visible = 'off';
            app.BranchDropDown_2.Visible = 'off';
            app.FlipBranch1_2.Visible = 'off';
            app.deleteBranch2.Visible = 'off';
        end

        % Button pushed function: deleteBranch3
        function deleteBranch3ButtonPushed(app, event)
            app.Branch3Label.Visible = 'off';
            app.BranchDropDown_3.Visible = 'off';
            app.FlipBranch1_3.Visible = 'off';
            app.deleteBranch3.Visible = 'off';
            app.deleteBranch2.Visible = 'on';
        end

        % Button pushed function: deleteBranch4
        function deleteBranch4ButtonPushed(app, event)
            app.Branch4Label.Visible = 'off';
            app.BranchDropDown_4.Visible = 'off';
            app.FlipBranch1_4.Visible = 'off';
            app.deleteBranch4.Visible = 'off';
            app.deleteBranch3.Visible = 'on';
        end

        % Button pushed function: ResetRotation
        function ResetRotationButtonPushed(app, event)
            % update rotate angles
            app.rotAngles = [0 0];
            View3DSegmentation(app);
        end

        % Value changed function: DisplayDistanceCheckbox
        function DisplayDistanceCheckboxValueChanged(app, event)
            value = app.DisplayDistanceCheckbox.Value;
            str = app.PWVPoints.Value;

            if value
                % grab distances
                eval(['ptRange=[' str '];']);
                app.PWVPoints.Value = [num2str(app.FullBranchDistance(ptRange(1))) ': ' num2str(app.FullBranchDistance(ptRange(end)))];
                app.PWVPointsLabel.Text = ['Vessel dist (mm) [' num2str(app.FullBranchDistance(ptRange(1))) ':' num2str(app.FullBranchDistance(ptRange(end))) ']'];
            else
                % convert distance to points
                out = textscan(str,'%f %f','Delimiter',':');
                [~, minIdx] = min(abs(app.FullBranchDistance-out{1}));
                [~, minIdx2] = min(abs(app.FullBranchDistance-out{2}));
                ptRange = minIdx:minIdx2;
                app.PWVPoints.Value = [num2str(ptRange(1)) ': ' num2str(ptRange(end))];
                app.PWVPointsLabel.Text = ['PWV Points [' num2str(ptRange(1)) ':' num2str(ptRange(end)) ']'];
            end
            view3D_wParams(app);
            plotWaveforms(app);
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            view3D_wParams(app);
            plotWaveforms(app);
        end

        % Value changed function: TimeframeSpinner
        function TimeframeSpinnerValueChanged(app, event)
            viewVelocityVectors(app);
        end

        % Button pushed function: CalculateWSS
        function CalculateWSSPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FlowProcessingUIFigure = uifigure('Visible', 'off');
            app.FlowProcessingUIFigure.Name = 'Pulse Wave Velocity Tool';
            app.TabGroup = uitabgroup(app.FlowProcessingUIFigure);
            app.LoadingandPreprocessingTab = uitab(app.TabGroup);
            app.LoadingandPreprocessingTab.Title = 'Loading and Preprocessing';
            app.LoadDataPanel = uipanel(app.LoadingandPreprocessingTab);
            app.LoadDataPanel.Title = 'Load Data';
            app.LoadPARRECDataButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadPARRECDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPARRECDataButtonPushed, true);
            app.LoadPARRECDataButton.Tooltip = {'load Philips 4D flow reconstructed par/rec data'};
            app.LoadPARRECDataButton.Text = 'Load PAR/REC Data';
            app.DataDirectoryEditFieldLabel = uilabel(app.LoadDataPanel);
            app.DataDirectoryEditFieldLabel.Text = 'Data Directory';
            app.DataDirectoryEditField = uieditfield(app.LoadDataPanel, 'text');
            app.DataDirectoryEditField.Editable = 'off';
            app.LoadSegmentationDicomsButton = uibutton(app.LoadDataPanel, 'push');
            app.LoadSegmentationDicomsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSegmentationDicomsButtonPushed, true);
            app.LoadSegmentationDicomsButton.Text = 'Load Segmentation Dicoms';
            app.SegmentationDirectoryEditFieldLabel = uilabel(app.LoadDataPanel);
            app.SegmentationDirectoryEditFieldLabel.Text = 'Segmentation Directory';
            app.SegmentationDirectoryEditField = uieditfield(app.LoadDataPanel, 'text');
            app.SegmentationDirectoryEditField.Editable = 'off';
            app.ScanInfoTable = uitable(app.LoadDataPanel);
            app.ScanInfoTable.ColumnName = {'matrix'; 'resolution (mm)'; 'time resolution (ms)'; 'cardiac frames'};
            app.ScanInfoTable.RowName = {};
            app.ScanInfoTable.ColumnSortable = [false false false false];
            app.ScanInfoTable.ColumnEditable = [false false false false];
            app.AutomaticSegmentation = uibutton(app.LoadDataPanel, 'push');
            app.AutomaticSegmentation.Tooltip = {'perform nn-UNET segmentation (aorta only)'};
            app.AutomaticSegmentation.Text = 'AI Segmentation';
            app.CropPanel = uipanel(app.LoadingandPreprocessingTab);
            app.CropPanel.Title = 'Crop';
            app.AxesX = uiaxes(app.CropPanel);
            app.AxesX.XTick = 0;
            app.AxesX.YTick = 0;
            app.AxesY = uiaxes(app.CropPanel);
            app.AxesY.XTick = [];
            app.AxesY.YTick = [];
            app.AxesZ = uiaxes(app.CropPanel);
            app.AxesZ.XTick = [];
            app.AxesZ.YTick = [];
            app.XrangeEditFieldLabel = uilabel(app.CropPanel);
            app.XrangeEditFieldLabel.Text = 'X-range';
            app.XrangeEditField = uieditfield(app.CropPanel, 'text');
            app.XrangeEditField.Editable = 'off';
            app.XrangeEditField.Value = '1';
            app.toXEditFieldLabel = uilabel(app.CropPanel);
            app.toXEditFieldLabel.Text = 'to';
            app.toXEditField = uieditfield(app.CropPanel, 'text');
            app.toXEditField.Value = 'res';
            app.toYEditFieldLabel = uilabel(app.CropPanel);
            app.toYEditFieldLabel.Text = 'to';
            app.toYEditField = uieditfield(app.CropPanel, 'text');
            app.toYEditField.Value = 'res';
            app.YrangeEditFieldLabel = uilabel(app.CropPanel);
            app.YrangeEditFieldLabel.Text = 'Y-range';
            app.YrangeEditField = uieditfield(app.CropPanel, 'text');
            app.YrangeEditField.Value = '1';
            app.toZEditFieldLabel = uilabel(app.CropPanel);
            app.toZEditFieldLabel.Text = 'to';
            app.toZEditField = uieditfield(app.CropPanel, 'text');
            app.toZEditField.Value = 'res';
            app.ZrangeEditFieldLabel = uilabel(app.CropPanel);
            app.ZrangeEditFieldLabel.Text = 'Z-range';
            app.ZrangeEditField = uieditfield(app.CropPanel, 'text');
            app.ZrangeEditField.Value = '1';
            app.CropButton = uibutton(app.CropPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.CropButton_2 = uibutton(app.CropPanel, 'push');
            app.CropButton_2.ButtonPushedFcn = createCallbackFcn(app, @CropButton_2Pushed, true);
            app.CropButton_2.Text = 'Crop';
            app.CropButton_3 = uibutton(app.CropPanel, 'push');
            app.CropButton_3.ButtonPushedFcn = createCallbackFcn(app, @CropButton_3Pushed, true);
            app.CropButton_3.Text = 'Crop';
            app.AdjustthresholdSliderLabel = uilabel(app.CropPanel);
            app.AdjustthresholdSliderLabel.Tooltip = {'the number of standard deviations outside of the background signal'};
            app.AdjustthresholdSliderLabel.Text = 'Adjust threshold';
            app.AdjustthresholdSlider = uislider(app.CropPanel);
            app.AdjustthresholdSlider.Limits = [0.5 4];
            app.AdjustthresholdSlider.MajorTicks = [1 2 3 4];
            app.AdjustthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @AdjustthresholdSliderValueChanged, true);
            app.AdjustthresholdSlider.MinorTicks = [0.6 0.8 1.2 1.4 1.6 1.8 2.2 2.4 2.6 2.8 3.2 3.4 3.6 3.8];
            app.AdjustthresholdSlider.Value = 2;
            app.DVisualizationPanel = uipanel(app.LoadingandPreprocessingTab);
            app.DVisualizationPanel.Title = '3D Visualization';
            app.View3D = uiaxes(app.DVisualizationPanel);
            app.View3D.XTick = [];
            app.View3D.YTick = [];
            app.RotateLeft = uibutton(app.DVisualizationPanel, 'push');
            app.RotateLeft.ButtonPushedFcn = createCallbackFcn(app, @RotateLeftButtonPushed, true);
            app.RotateLeft.Text = '<';
            app.RotateRight = uibutton(app.DVisualizationPanel, 'push');
            app.RotateRight.ButtonPushedFcn = createCallbackFcn(app, @RotateRightButtonPushed, true);
            app.RotateRight.Text = '>';
            app.Rotate = uilabel(app.DVisualizationPanel);
            app.Rotate.Text = 'Rotate';
            app.RotateDown = uibutton(app.DVisualizationPanel, 'push');
            app.RotateDown.ButtonPushedFcn = createCallbackFcn(app, @RotateDownButtonPushed, true);
            app.RotateDown.Text = '▼';
            app.RotateUp = uibutton(app.DVisualizationPanel, 'push');
            app.RotateUp.ButtonPushedFcn = createCallbackFcn(app, @RotateUpButtonPushed, true);
            app.RotateUp.Text = '▲';
            app.ResetRotation = uibutton(app.DVisualizationPanel, 'push');
            app.ResetRotation.ButtonPushedFcn = createCallbackFcn(app, @ResetRotationButtonPushed, true);
            app.ResetRotation.Tooltip = {'Reset rotation to original view'};
            app.ResetRotation.Text = 'Reset rotation';
            app.ProcessingPanel = uipanel(app.LoadingandPreprocessingTab);
            app.ProcessingPanel.Title = 'Processing';
            app.UnwrapVelocity = uibutton(app.ProcessingPanel, 'push');
            app.UnwrapVelocity.ButtonPushedFcn = createCallbackFcn(app, @UnwrapVelocityButtonPushed, true);
            app.UnwrapVelocity.Tooltip = {'Perform 4D Laplacian unwrapping'};
            app.UnwrapVelocity.Text = 'Unwrap Velocity';
            app.PulseWaveVelocityPushButton = uibutton(app.ProcessingPanel, 'push');
            app.PulseWaveVelocityPushButton.ButtonPushedFcn = createCallbackFcn(app, @PulseWaveVelocityPushButtonButtonPushed, true);
            app.PulseWaveVelocityPushButton.Tooltip = {'calculate segmentation centerline for flow/PWV measurement'};
            app.PulseWaveVelocityPushButton.Text = 'Pulse Wave Velocity';
            app.MapsPushButton = uibutton(app.ProcessingPanel, 'push');
            app.MapsPushButton.ButtonPushedFcn = createCallbackFcn(app, @MapsPushButtonPushed, true);
            app.MapsPushButton.Tooltip = {'calculate and display mapped parameters'};
            app.MapsPushButton.Text = 'Maps';
            app.Maps = uitab(app.TabGroup);
            app.Maps.Title = 'Maps';
            app.VelocityVectorGroup = uipanel(app.Maps);
            app.VelocityVectorGroup.Title = 'Velocity Vectors';
            app.VelocityVectorsPlot = uiaxes(app.VelocityVectorGroup);
            app.VelocityVectorsPlot.XTick = [];
            app.VelocityVectorsPlot.YTick = [];
            app.TimeframeSpinnerLabel = uilabel(app.VelocityVectorGroup);
            app.TimeframeSpinnerLabel.Enable = 'off';
            app.TimeframeSpinnerLabel.Text = 'Time frame';
            app.TimeframeSpinner = uispinner(app.VelocityVectorGroup);
            app.TimeframeSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeframeSpinnerValueChanged, true);
            app.TimeframeSpinner.Enable = 'off';
            app.quiverscaleEditFieldLabel = uilabel(app.VelocityVectorGroup);
            app.quiverscaleEditFieldLabel.Enable = 'off';
            app.quiverscaleEditFieldLabel.Text = 'quiver scale';
            app.minQuiverEditField = uieditfield(app.VelocityVectorGroup, 'text');
            app.minQuiverEditField.Enable = 'off';
            app.minQuiverEditField.Value = '2';
            app.toXEditFieldLabel_2 = uilabel(app.VelocityVectorGroup);
            app.toXEditFieldLabel_2.Enable = 'off';
            app.toXEditFieldLabel_2.Text = 'to';
            app.maxQuiverEditField = uieditfield(app.VelocityVectorGroup, 'text');
            app.maxQuiverEditField.Enable = 'off';
            app.maxQuiverEditField.Value = '10';
            app.velocityVectorEditFieldLabel = uilabel(app.VelocityVectorGroup);
            app.velocityVectorEditFieldLabel.Enable = 'off';
            app.velocityVectorEditFieldLabel.Text = 'velocity scale (cm/s)';
            app.minVelocityVectorEditField = uieditfield(app.VelocityVectorGroup, 'text');
            app.minVelocityVectorEditField.Enable = 'off';
            app.minVelocityVectorEditField.Value = '0';
            app.velocityVectortoEditFieldLabel = uilabel(app.VelocityVectorGroup);
            app.velocityVectortoEditFieldLabel.Enable = 'off';
            app.velocityVectortoEditFieldLabel.Text = 'to';
            app.maxVelocityVectorEditField = uieditfield(app.VelocityVectorGroup, 'text');
            app.maxVelocityVectorEditField.Enable = 'off';
            app.maxVelocityVectorEditField.Value = 'max';
            app.WSSGroup = uipanel(app.Maps);
            app.WSSGroup.Title = 'Wall Shear Stress';
            app.WSSPlot = uiaxes(app.WSSGroup);
            app.WSSPlot.XTick = [];
            app.WSSPlot.YTick = [];
            app.TimeframeSpinner_2Label = uilabel(app.WSSGroup);
            app.TimeframeSpinner_2Label.Enable = 'off';
            app.TimeframeSpinner_2Label.Text = 'Time frame';
            app.TimeframeSpinner_2 = uispinner(app.WSSGroup);
            app.TimeframeSpinner_2.Enable = 'off';
            app.FramesButtonGroup = uibuttongroup(app.WSSGroup);
            app.FramesButtonGroup.Enable = 'off';
            app.FramesButtonGroup.Title = 'Frames';
            app.AllButton = uitogglebutton(app.FramesButtonGroup);
            app.AllButton.Text = 'All';
            app.AllButton.Value = true;
            app.PeaksystoleButton = uitogglebutton(app.FramesButtonGroup);
            app.PeaksystoleButton.Text = 'Peak systole';
            app.ViscosityPasLabel = uilabel(app.WSSGroup);
            app.ViscosityPasLabel.Enable = 'off';
            app.ViscosityPasLabel.Text = 'Viscosity (Pa•s)';
            app.ViscosityPasEditField = uieditfield(app.WSSGroup, 'text');
            app.ViscosityPasEditField.Enable = 'off';
            app.ViscosityPasEditField.Value = '0.0032';
            app.WalleffectsCheckBox = uicheckbox(app.WSSGroup);
            app.WalleffectsCheckBox.Enable = 'off';
            app.WalleffectsCheckBox.Text = 'Wall effects';
            app.CalculateWSS = uibutton(app.WSSGroup, 'push');
            app.CalculateWSS.ButtonPushedFcn = createCallbackFcn(app, @CalculateWSSPushed, true);
            app.CalculateWSS.Enable = 'off';
            app.CalculateWSS.Tooltip = {'calculate WSS'};
            app.CalculateWSS.Text = 'Calculate WSS';
            app.FlowandPulseWaveVelocityTab = uitab(app.TabGroup);
            app.FlowandPulseWaveVelocityTab.Title = 'Flow and Pulse Wave Velocity';
            app.WaveformsDisplay = uiaxes(app.FlowandPulseWaveVelocityTab);
            xlabel(app.WaveformsDisplay, 'Cardiac time (ms)')
            ylabel(app.WaveformsDisplay, 'Flow (mL/s)')
            app.PWVCalcDisplay = uiaxes(app.FlowandPulseWaveVelocityTab);
            xlabel(app.PWVCalcDisplay, 'delay (ms)')
            ylabel(app.PWVCalcDisplay, 'distance (mm)')
            app.SegmentationAndCenterline = uipanel(app.FlowandPulseWaveVelocityTab);
            app.SegmentationAndCenterline.Title = '3D View';
            app.View3D_2 = uiaxes(app.SegmentationAndCenterline);
            app.View3D_2.XTick = [];
            app.View3D_2.YTick = [];
            app.Reset3DviewButton = uibutton(app.SegmentationAndCenterline, 'push');
            app.Reset3DviewButton.ButtonPushedFcn = createCallbackFcn(app, @Reset3DviewButtonPushed, true);
            app.Reset3DviewButton.Tooltip = {'Reset 3D view with overlaid branch number(s) '};
            app.Reset3DviewButton.Text = 'Reset 3D view';
            app.DisplayDistanceCheckbox = uicheckbox(app.SegmentationAndCenterline);
            app.DisplayDistanceCheckbox.ValueChangedFcn = createCallbackFcn(app, @DisplayDistanceCheckboxValueChanged, true);
            app.DisplayDistanceCheckbox.Tooltip = {'Display distance on 3D view and turn on distance-based PWV point selection'};
            app.DisplayDistanceCheckbox.Text = 'Display distance';
            app.ParameterLabel = uilabel(app.SegmentationAndCenterline);
            app.ParameterLabel.Visible = 'off';
            app.ParameterLabel.Text = 'Parameter:';
            app.ParameterDropDown = uidropdown(app.SegmentationAndCenterline);
            app.ParameterDropDown.Items = {'Total Flow', 'Peak Flow', 'Mean Velocity', 'Peak Velocity'};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Enable = 'off';
            app.ParameterDropDown.Visible = 'off';
            app.ParameterDropDown.Value = 'Total Flow';
            app.BranchNumberTitle = uilabel(app.FlowandPulseWaveVelocityTab);
            app.BranchNumberTitle.Text = 'Set branches for vessel';
            app.CheckcenterlinecalculateflowButton = uibutton(app.FlowandPulseWaveVelocityTab, 'push');
            app.CheckcenterlinecalculateflowButton.ButtonPushedFcn = createCallbackFcn(app, @CheckcenterlinecalculateflowButtonPushed, true);
            app.CheckcenterlinecalculateflowButton.Text = 'Check centerline, calculate flow';
            app.PWVPointsTitle = uilabel(app.FlowandPulseWaveVelocityTab);
            app.PWVPointsTitle.Text = 'Set PWV measurement points';
            app.PWVPointsLabel = uilabel(app.FlowandPulseWaveVelocityTab);
            app.PWVPointsLabel.Text = 'PWV points: ';
            app.PWVPoints = uieditfield(app.FlowandPulseWaveVelocityTab, 'text');
            app.PWVPoints.Tooltip = {'Centerline point labels used for PWV calculation. '};
            app.PlotWaveformsButton = uibutton(app.FlowandPulseWaveVelocityTab, 'push');
            app.PlotWaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWaveformsButtonPushed, true);
            app.PlotWaveformsButton.Text = 'Examine flow waveforms';
            app.CalculatePWV = uibutton(app.FlowandPulseWaveVelocityTab, 'push');
            app.CalculatePWV.ButtonPushedFcn = createCallbackFcn(app, @CalculatePWVButtonPushed, true);
            app.CalculatePWV.Text = 'Calculate PWV';
            app.PWVType = uidropdown(app.FlowandPulseWaveVelocityTab);
            app.PWVType.Items = {'Cross-correlation', 'Wavelet', 'Maximum likelihood'};
            app.PWVType.Value = 'Wavelet';
            app.PWVDisplayTitle = uilabel(app.FlowandPulseWaveVelocityTab);
            app.PWVDisplayTitle.Text = 'Calculated PWV (m/s)';
            app.PWVDisplay = uieditfield(app.FlowandPulseWaveVelocityTab, 'text');
            app.PWVDisplay.Editable = 'off';
            app.PWVDisplay.Tooltip = {''};
            app.SavingTitle = uilabel(app.FlowandPulseWaveVelocityTab);
            app.SavingTitle.Text = 'Saving';
            app.SaveResultsCallback = uibutton(app.FlowandPulseWaveVelocityTab, 'push');
            app.SaveResultsCallback.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsCallbackButtonPushed, true);
            app.SaveResultsCallback.Text = 'Save';
            app.SaveName = uidropdown(app.FlowandPulseWaveVelocityTab);
            app.SaveName.Items = {'Global PWV', 'Ascending Aorta', 'Descending Aorta', 'Left Carotid', 'Right Carotid'};
            app.SaveName.Value = 'Global PWV';
            app.timeResolvedSegCheckbox = uicheckbox(app.FlowandPulseWaveVelocityTab);
            app.timeResolvedSegCheckbox.Tooltip = {'If checked'; ' non-rigid registration is performed for each cardiac time frame to get time-resolved segmentations. Takes time!'};
            app.timeResolvedSegCheckbox.Text = 'Time-resolved segmentation';
            app.FlipBranch1 = uicheckbox(app.FlowandPulseWaveVelocityTab);
            app.FlipBranch1.Tooltip = {''};
            app.FlipBranch1.Text = 'Flip branch';
            app.BranchDropDownLabel = uilabel(app.FlowandPulseWaveVelocityTab);
            app.BranchDropDownLabel.Tooltip = {''};
            app.BranchDropDownLabel.Text = 'Branch 1';
            app.BranchDropDown = uidropdown(app.FlowandPulseWaveVelocityTab);
            app.BranchDropDown.Items = {'1'};
            app.BranchDropDown.Tooltip = {''};
            app.BranchDropDown.Value = '1';
            app.deleteBranch2 = uibutton(app.FlowandPulseWaveVelocityTab, 'push');
            app.deleteBranch2.ButtonPushedFcn = createCallbackFcn(app, @deleteBranch2ButtonPushed, true);
            app.deleteBranch2.Visible = 'off';
            app.deleteBranch2.Tooltip = {'remove this branch from list'};
            app.deleteBranch2.Text = 'X';
            app.FlipBranch1_2 = uicheckbox(app.FlowandPulseWaveVelocityTab);
            app.FlipBranch1_2.Visible = 'off';
            app.FlipBranch1_2.Tooltip = {''};
            app.FlipBranch1_2.Text = 'Flip branch';
            app.Branch2Label = uilabel(app.FlowandPulseWaveVelocityTab);
            app.Branch2Label.Visible = 'off';
            app.Branch2Label.Tooltip = {''};
            app.Branch2Label.Text = 'Branch 2';
            app.BranchDropDown_2 = uidropdown(app.FlowandPulseWaveVelocityTab);
            app.BranchDropDown_2.Items = {'1'};
            app.BranchDropDown_2.Visible = 'off';
            app.BranchDropDown_2.Tooltip = {''};
            app.BranchDropDown_2.Value = '1';
            app.AddbranchButton = uibutton(app.FlowandPulseWaveVelocityTab, 'push');
            app.AddbranchButton.ButtonPushedFcn = createCallbackFcn(app, @AddbranchButtonPushed, true);
            app.AddbranchButton.Tooltip = {'Push to concatenate multiple branches (up to 4)'};
            app.AddbranchButton.Text = 'Add branch';
            app.deleteBranch3 = uibutton(app.FlowandPulseWaveVelocityTab, 'push');
            app.deleteBranch3.ButtonPushedFcn = createCallbackFcn(app, @deleteBranch3ButtonPushed, true);
            app.deleteBranch3.Visible = 'off';
            app.deleteBranch3.Tooltip = {'remove this branch from list'};
            app.deleteBranch3.Text = 'X';
            app.FlipBranch1_3 = uicheckbox(app.FlowandPulseWaveVelocityTab);
            app.FlipBranch1_3.Visible = 'off';
            app.FlipBranch1_3.Tooltip = {''};
            app.FlipBranch1_3.Text = 'Flip branch';
            app.Branch3Label = uilabel(app.FlowandPulseWaveVelocityTab);
            app.Branch3Label.Visible = 'off';
            app.Branch3Label.Tooltip = {''};
            app.Branch3Label.Text = 'Branch 3';
            app.BranchDropDown_3 = uidropdown(app.FlowandPulseWaveVelocityTab);
            app.BranchDropDown_3.Items = {'1'};
            app.BranchDropDown_3.Visible = 'off';
            app.BranchDropDown_3.Tooltip = {''};
            app.BranchDropDown_3.Value = '1';
            app.FlipBranch1_4 = uicheckbox(app.FlowandPulseWaveVelocityTab);
            app.FlipBranch1_4.Visible = 'off';
            app.FlipBranch1_4.Tooltip = {''};
            app.FlipBranch1_4.Text = 'Flip branch';
            app.Branch4Label = uilabel(app.FlowandPulseWaveVelocityTab);
            app.Branch4Label.Visible = 'off';
            app.Branch4Label.Tooltip = {''};
            app.Branch4Label.Text = 'Branch 4';
            app.BranchDropDown_4 = uidropdown(app.FlowandPulseWaveVelocityTab);
            app.BranchDropDown_4.Items = {'1'};
            app.BranchDropDown_4.Visible = 'off';
            app.BranchDropDown_4.Tooltip = {''};
            app.BranchDropDown_4.Value = '1';
            app.deleteBranch4 = uibutton(app.FlowandPulseWaveVelocityTab, 'push');
            app.deleteBranch4.ButtonPushedFcn = createCallbackFcn(app, @deleteBranch4ButtonPushed, true);
            app.deleteBranch4.Visible = 'off';
            app.deleteBranch4.Tooltip = {'remove this branch from list'};
            app.deleteBranch4.Text = 'X';
            app.PWVDisplayTitle_2 = uilabel(app.FlowandPulseWaveVelocityTab);
            app.PWVDisplayTitle_2.Text = 'Fit R^2';
            app.R2Display = uieditfield(app.FlowandPulseWaveVelocityTab, 'text');
            app.R2Display.Editable = 'off';
            app.R2Display.Tooltip = {''};
            app.findBestFit_checkbox = uicheckbox(app.FlowandPulseWaveVelocityTab);
            app.findBestFit_checkbox.Tooltip = {'If checked, multiple PWV are performed and the one with best R2 is reported to user. Only for Wavelet and Cross-correlation'};
            app.findBestFit_checkbox.Text = 'Find best fit';
            app.ResetWorkSpace = uitab(app.TabGroup);
            app.ResetWorkSpace.Title = 'Reset Workspace';
            app.CleardataandrestartanalysisButton = uibutton(app.ResetWorkSpace, 'push');
            app.CleardataandrestartanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @CleardataandrestartanalysisButtonPushed, true);
            app.CleardataandrestartanalysisButton.Text = 'Clear data and restart analysis';
            % Show the figure after all components are created
            app.FlowProcessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlowProcessing2

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FlowProcessingUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.FlowProcessingUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FlowProcessingUIFigure)
        end
    end
end